classdef ELABorate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        NetlistTextArea        matlab.ui.control.TextArea
        TabGroup               matlab.ui.container.TabGroup
        EquationsTab           matlab.ui.container.Tab
        EquationWindow         matlab.ui.control.UIAxes
        SymbolicTab            matlab.ui.container.Tab
        SymbolicWindow         matlab.ui.control.UIAxes
        NumericalTab           matlab.ui.container.Tab
        NumericalWindow        matlab.ui.control.UIAxes
        TransferTab            matlab.ui.container.Tab
        TransferWindow         matlab.ui.control.UIAxes
        InnodeEditFieldLabel   matlab.ui.control.Label
        InnodeEditField        matlab.ui.control.NumericEditField
        OutnodeEditFieldLabel  matlab.ui.control.Label
        OutnodeEditField       matlab.ui.control.NumericEditField
        SymbolicTFButton       matlab.ui.control.Button
        NumericalTFButton      matlab.ui.control.Button
        TabGroup2              matlab.ui.container.TabGroup
        StepTab                matlab.ui.container.Tab
        StepPlot               matlab.ui.control.UIAxes
        BodeTab                matlab.ui.container.Tab
        StabilityTab           matlab.ui.container.Tab
        StabilityWindow        matlab.ui.control.UIAxes
        ImportButton           matlab.ui.control.Button
        ELABorateLabel         matlab.ui.control.Label
        ClearButton            matlab.ui.control.Button
        SimplifyButton         matlab.ui.control.Button
        AnalyzeButton          matlab.ui.control.Button
        ImportedLabel          matlab.ui.control.Label
        ConsoleLabel           matlab.ui.control.Label
    end

    properties (Access = private)
        netlist; circuit;
    end
    
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %disableDefaultInteractivity(app.EquationWindow);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Select a circuit file.');

            if isequal(file, 0)
                app.ImportedLabel.Text = 'No file chosen.';
            else
                app.netlist = fullfile(path, file);
                app.NetlistTextArea.Value = fileread(file);
                app.NetlistTextArea.FontColor = [0 0 0];
                app.ImportedLabel.Text = file;
            end
            figure(app.UIFigure); % Refocuses program window.
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ImportedLabel.Text = '...';
            app.NetlistTextArea.Value = 'Import or write netlist';
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            file = fopen('.tmp.cir', 'wt');
            fprintf(file, '%s\n', string(app.NetlistTextArea.Value));
            fclose(file);
            
            % Setup.
            app.ConsoleLabel.Text = '';
            app.ConsoleLabel.Text = 'Creating circuit object...';
            app.circuit = Circuit('.tmp.cir'); % Check this with console.
            delete('.tmp.cir');
            
            % Equation output.
            app.ConsoleLabel.Text = sprintf('%s\nRunning symbolic analysis...', app.ConsoleLabel.Text);
            app.circuit.analyze; cla(app.EquationWindow);
            text(app.EquationWindow, 0, 0.5, ['$' latex(app.circuit.equations) '$'], 'Interpreter',"latex");
            
            % Symbolic output.
            cla(app.SymbolicWindow);
            output = [ ...
                latex(app.circuit.symbolic_node_voltages) ...
                latex(app.circuit.symbolic_source_currents)
            ];
            text(app.SymbolicWindow, 0, 0.5, ['$' output '$'], 'Interpreter', 'latex');
            
            
            % Numerical output.
            app.ConsoleLabel.Text = sprintf('%s\nRunning numerical analysis...', app.ConsoleLabel.Text);
            app.circuit.numerical; cla(app.NumericalWindow);
            output = [ ...
                latex(app.circuit.numerical_node_voltages) ...
                latex(app.circuit.numerical_source_currents)
            ];
            text(app.NumericalWindow, 0, 0.5, ['$' output '$'], 'Interpreter', 'latex');
        end

        % Button pushed function: SymbolicTFButton
        function SymbolicTFButtonPushed(app, event)
            cla(app.TransferWindow);
            app.ConsoleLabel.Text = sprintf('%s\nDefining sym. transfer function...', app.ConsoleLabel.Text);
            in = uint8(app.InnodeEditField.Value);
            out = uint8(app.OutnodeEditField.Value);
            app.circuit.transfer(in, out, false);
            
            output = sprintf('$$%s$$', latex(app.circuit.symbolic_transfer_function));
            text(app.TransferWindow, 0, 0.5, output, 'Interpreter', 'latex');
        end

        % Button pushed function: NumericalTFButton
        function NumericalTFButtonPushed(app, event)
            cla(app.TransferWindow);
            app.ConsoleLabel.Text = sprintf('%s\nDefining num. transfer function...', app.ConsoleLabel.Text);
            in = uint8(app.InnodeEditField.Value);
            out = uint8(app.OutnodeEditField.Value);
            app.circuit.transfer(in, out, true);
            
            % Convert numerical tf-object to symbolic for printing.
            [num,den] = tfdata(app.circuit.numerical_transfer_function); syms s;
            sym_from_tf = poly2sym(cell2mat(num), s) / poly2sym(cell2mat(den), s);
            
            output = sprintf('$$%s$$', latex(sym_from_tf));
            text(app.TransferWindow, 0, 0.5, output, 'Interpreter', 'latex');
            
            [y,~] = step(app.circuit.numerical_transfer_function);
            plot(app.StepPlot, y(:,:,1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetlistTextArea = uitextarea(app.UIFigure);
            app.NetlistTextArea.Value = {'Vin 3 0 Symbolic'; 'R2 3 2 1000'; 'R1 1 0 1000'; 'C1 1 0 1E-6'; 'C2 2 1 10E-6'; 'L1 1 0 0.001'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EquationsTab = uitab(app.TabGroup);
            app.EquationsTab.Title = 'Equations';
            app.EquationWindow = uiaxes(app.EquationsTab);
            title(app.EquationWindow, '')
            xlabel(app.EquationWindow, '')
            ylabel(app.EquationWindow, '')
            app.EquationWindow.XLim = [0 1];
            app.EquationWindow.YLim = [0 1];
            app.EquationWindow.ZLim = [0 1];
            app.EquationWindow.XTick = [];
            app.EquationWindow.YTick = [];
            app.SymbolicTab = uitab(app.TabGroup);
            app.SymbolicTab.Title = 'Symbolic';
            app.SymbolicWindow = uiaxes(app.SymbolicTab);
            title(app.SymbolicWindow, '')
            xlabel(app.SymbolicWindow, '')
            ylabel(app.SymbolicWindow, '')
            app.SymbolicWindow.XLim = [0 1];
            app.SymbolicWindow.YLim = [0 1];
            app.SymbolicWindow.ZLim = [0 1];
            app.SymbolicWindow.XTick = [];
            app.SymbolicWindow.YTick = [];
            app.NumericalTab = uitab(app.TabGroup);
            app.NumericalTab.Title = 'Numerical';
            app.NumericalWindow = uiaxes(app.NumericalTab);
            title(app.NumericalWindow, '')
            xlabel(app.NumericalWindow, '')
            ylabel(app.NumericalWindow, '')
            app.NumericalWindow.XLim = [0 1];
            app.NumericalWindow.YLim = [0 1];
            app.NumericalWindow.ZLim = [0 1];
            app.NumericalWindow.XTick = [];
            app.NumericalWindow.YTick = [];
            app.TransferTab = uitab(app.TabGroup);
            app.TransferTab.Title = 'Transfer';
            app.TransferWindow = uiaxes(app.TransferTab);
            title(app.TransferWindow, '')
            xlabel(app.TransferWindow, '')
            ylabel(app.TransferWindow, '')
            app.TransferWindow.XLim = [0 1];
            app.TransferWindow.YLim = [0 1];
            app.TransferWindow.ZLim = [0 1];
            app.TransferWindow.XTick = [];
            app.TransferWindow.YTick = [];
            app.InnodeEditFieldLabel = uilabel(app.TransferTab);
            app.InnodeEditFieldLabel.Text = 'In node';
            app.InnodeEditField = uieditfield(app.TransferTab, 'numeric');
            app.InnodeEditField.Limits = [1 Inf];
            app.InnodeEditField.Value = 1;
            app.OutnodeEditFieldLabel = uilabel(app.TransferTab);
            app.OutnodeEditFieldLabel.Text = 'Out node';
            app.OutnodeEditField = uieditfield(app.TransferTab, 'numeric');
            app.OutnodeEditField.Limits = [1 Inf];
            app.OutnodeEditField.Value = 1;
            app.SymbolicTFButton = uibutton(app.TransferTab, 'push');
            app.SymbolicTFButton.ButtonPushedFcn = createCallbackFcn(app, @SymbolicTFButtonPushed, true);
            app.SymbolicTFButton.Text = 'Symbolic TF';
            app.NumericalTFButton = uibutton(app.TransferTab, 'push');
            app.NumericalTFButton.ButtonPushedFcn = createCallbackFcn(app, @NumericalTFButtonPushed, true);
            app.NumericalTFButton.Text = 'Numerical TF';
            app.TabGroup2 = uitabgroup(app.TransferTab);
            app.StepTab = uitab(app.TabGroup2);
            app.StepTab.Title = 'Step';
            app.StepPlot = uiaxes(app.StepTab);
            title(app.StepPlot, '')
            xlabel(app.StepPlot, 'Time (ms)')
            ylabel(app.StepPlot, 'Amplitude')
            app.BodeTab = uitab(app.TabGroup2);
            app.BodeTab.Title = 'Bode';
            app.StabilityTab = uitab(app.TabGroup);
            app.StabilityTab.Title = 'Stability';
            app.StabilityWindow = uiaxes(app.StabilityTab);
            title(app.StabilityWindow, '')
            xlabel(app.StabilityWindow, '')
            ylabel(app.StabilityWindow, '')
            app.StabilityWindow.XLim = [0 1];
            app.StabilityWindow.YLim = [0 1];
            app.StabilityWindow.ZLim = [0 1];
            app.StabilityWindow.XTick = [];
            app.StabilityWindow.YTick = [];
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ELABorateLabel = uilabel(app.UIFigure);
            app.ELABorateLabel.Text = 'ELABorate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SimplifyButton = uibutton(app.UIFigure, 'push');
            app.SimplifyButton.Text = 'Simplify';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ImportedLabel = uilabel(app.UIFigure);
            app.ImportedLabel.Text = '...';
            app.ConsoleLabel = uilabel(app.UIFigure);
            app.ConsoleLabel.Text = 'Console';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELABorate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end